classdef RCP_controller_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Welcome_Screen                  matlab.ui.container.Panel
        Welcome_Screen_UIAxes           matlab.ui.control.UIAxes
        Welcome_Screen_Title            matlab.ui.control.Label
        Welcome_Screen_STARTButton      matlab.ui.control.Button
        Welcome_Screen_STARTButton_Instructions  matlab.ui.control.Label
        Settings_Screen                 matlab.ui.container.Panel
        Settings_Screen_Title           matlab.ui.control.Label
        Settings_Screen_CONTINUEButton  matlab.ui.control.Button
        Settings_Screen_CommunicateWithRobotCheckBox  matlab.ui.control.CheckBox
        Settings_Screen_UseOCRToGatherDataCheckBox  matlab.ui.control.CheckBox
        Settings_Screen_ShowCommandsBeingSentCheckBox  matlab.ui.control.CheckBox
        Settings_Screen_EnterCommandsManuallyCheckBox  matlab.ui.control.CheckBox
        Settings_Screen_OutputPreferencesLabel  matlab.ui.control.Label
        Settings_Screen_RobotControlDesiredLabel  matlab.ui.control.Label
        Settings_Screen_ControlUsingNEErrorCheckBox  matlab.ui.control.CheckBox
        BlankScreenTemplatePanel        matlab.ui.container.Panel
        Button                          matlab.ui.control.StateButton
        Bluetooth_Setup_Screen          matlab.ui.container.Panel
        Bluetooth_Setup_Screen_Title    matlab.ui.control.Label
        BluetoothDeviceNameEditFieldLabel  matlab.ui.control.Label
        Bluetooth_Setup_Screen_UserInput  matlab.ui.control.EditField
        Bluetooth_Setup_Screen_CONNECTButton  matlab.ui.control.Button
        Bluetooth_Setup_Screen_GOBACKTOSETTINGSButton  matlab.ui.control.Button
        Bluetooth_Setup_Screen_CONTINUEButton  matlab.ui.control.Button
        DeviceConnectivityStatusLabel   matlab.ui.control.Label
        Bluetooth_Setup_Screen_DeviceConnectedLamp  matlab.ui.control.Lamp
        Bluetooth_Setup_Screen_ConnectMessage  matlab.ui.control.Label
        Manual_Controller_Screen        matlab.ui.container.Panel
        Manual_Controller_Screen_Title  matlab.ui.control.Label
        Manual_Controller_Screen_MOVEMENTSPEEDButtonGroup  matlab.ui.container.ButtonGroup
        Manual_Controller_Screen_FastButton  matlab.ui.control.RadioButton
        Manual_Controller_Screen_NormalButton  matlab.ui.control.RadioButton
        Manual_Controller_Screen_SlowButton  matlab.ui.control.RadioButton
        Manual_Controller_Screen_MARKButton  matlab.ui.control.Button
        Manual_Controller_Screen_MotionCommands  matlab.ui.container.Panel
        Manual_Controller_Screen_FORWARDButton  matlab.ui.control.Button
        Manual_Controller_Screen_REVERSEButton  matlab.ui.control.Button
        Manual_Controller_Screen_LEFTButton  matlab.ui.control.Button
        Manual_Controller_Screen_RIGHTButton  matlab.ui.control.Button
        Manual_Controller_Screen_ROTATELEFTButton  matlab.ui.control.Button
        Manual_Controller_Screen_ROTATERIGHTButton  matlab.ui.control.Button
        Manual_Controller_Screen_STOPButton  matlab.ui.control.Button
        Manual_Controller_Screen_SWButton  matlab.ui.control.Button
        Manual_Controller_Screen_NWButton  matlab.ui.control.Button
        Manual_Controller_Screen_NEButton  matlab.ui.control.Button
        Manual_Controller_Screen_SEButton  matlab.ui.control.Button
        Manual_Controller_Screen_PAUSEButton  matlab.ui.control.Button
        Manual_Controller_Screen_KILLButton  matlab.ui.control.Button
        Manual_Controller_Screen_LastCommandSent_Panel  matlab.ui.container.Panel
        Manual_Controller_Screen_LastCommandSent_Title  matlab.ui.control.Label
        Manual_Controller_Screen_LastCommandSent_Holder  matlab.ui.container.Panel
        Manual_Controller_Screen_LastCommandSent  matlab.ui.control.Label
        Manual_Controller_Screen_ACTION1Button  matlab.ui.control.Button
        Manual_Controller_Screen_ACTION2Button  matlab.ui.control.Button
        Manual_Controller_Screen_ACTION3Button  matlab.ui.control.Button
        Manual_Controller_Screen_ACTION4Button  matlab.ui.control.Button
        Manual_Controller_Screen_BACKTOOCRCONTROLButton  matlab.ui.control.Button
        OCR_Setup_Screen                matlab.ui.container.Panel
        OCR_Setup_Screen_Title          matlab.ui.control.Label
        OCR_Setup_Screen_SelectOCRReadLocationButton_Instructions  matlab.ui.control.Label
        OCR_Setup_Screen_SelectOCRReadLocationButton  matlab.ui.control.Button
        OCR_Setup_Screen_PreviewSelectionButton  matlab.ui.control.Button
        OCR_Setup_Screen_GOBACKTOSETTINGSButton  matlab.ui.control.Button
        OCR_Setup_Screen_CONTINUEButton  matlab.ui.control.Button
        OCR_Setup_Screen_UIAxes_Holder  matlab.ui.container.Panel
        OCR_Setup_Screen_UIAxes         matlab.ui.control.UIAxes
        Pause_Menu_Screen               matlab.ui.container.Panel
        Pause_Menu_Screen_Title         matlab.ui.control.Label
        Pause_Menu_Screen_RETURNTOPREVIOUSSCREENButton  matlab.ui.control.Button
        Pause_Menu_Screen_GOTOSETTINGSButton  matlab.ui.control.Button
        Pause_Menu_Screen_QUITButton    matlab.ui.control.Button
        OCR_Controller_Screen           matlab.ui.container.Panel
        OCR_Controller_Screen_Title     matlab.ui.control.Label
        OCR_Controller_Screen_InitOrientationButton  matlab.ui.control.Button
        OCR_Controller_Screen_InitOrientationButton_Instructions  matlab.ui.control.Label
        OCR_Controller_Screen_PreviewSelectionButton  matlab.ui.control.Button
        OCR_Controller_Screen_UIAxes_Holder  matlab.ui.container.Panel
        OCR_Controller_Screen_UIAxes    matlab.ui.control.UIAxes
        OCR_Controller_Screen_PAUSEButton  matlab.ui.control.Button
        OCR_Controller_Screen_EnableCommands  matlab.ui.control.Button
        OCR_Controller_Screen_STOPROBOTButton  matlab.ui.control.Button
        OCR_Controller_Screen_AdjustOCRButton  matlab.ui.control.Button
        OCR_Controller_Screen_LastCommandSent_Panel  matlab.ui.container.Panel
        OCR_Controller_Screen_LastCommandSent_Title  matlab.ui.control.Label
        OCR_Controller_Screen_LastCommandSent_Holder  matlab.ui.container.Panel
        OCR_Controller_Screen_LastCommandSent  matlab.ui.control.Label
        OCR_Controller_Screen_MANUALOVERRIDEButton  matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        updatedNorthingErrorLabel       matlab.ui.control.Label
        ErrorNLabel                     matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        updatedEastingErrorLabel        matlab.ui.control.Label
        ErrorELabel                     matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        updatedDesiredOffsetLabel       matlab.ui.control.Label
        CurrentDesiredOffsetLabel       matlab.ui.control.Label
        NE_Error_Controller_Screen      matlab.ui.container.Panel
        NE_Error_Controller_Screen_Title  matlab.ui.control.Label
        NorthingErrorEditFieldLabel     matlab.ui.control.Label
        NE_Error_Controller_Screen_NorthingErrorInput  matlab.ui.control.NumericEditField
        EastingErrorEditFieldLabel      matlab.ui.control.Label
        NE_Error_Controller_Screen_EastingErrorInput  matlab.ui.control.NumericEditField
        NE_Error_Controller_Screen_SendCommandButton  matlab.ui.control.Button
        NE_Error_Controller_Screen_STOPROBOTButton  matlab.ui.control.Button
        NE_Error_Controller_Screen_LastCommandSent_Panel  matlab.ui.container.Panel
        NE_Error_Controller_Screen_LastCommandSent_Title  matlab.ui.control.Label
        NE_Error_Controller_Screen_LastCommandSent_Holder  matlab.ui.container.Panel
        NE_Error_Controller_Screen_LastCommandSent  matlab.ui.control.Label
        NE_Error_Controller_Screen_PAUSEButton  matlab.ui.control.Button
    end


    properties (Access = public)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%% GRAPHICAL PROPERTIES %%%%%%%%%%%%
        
        openPanel_position % This is the location of the active screen
        
        % These hold the position data for where different screens are to be held while not active
        storageSpace_BlankScreenTemplatePanel
        storageSpace_Welcome_Screen
        storageSpace_Settings_Screen
        storageSpace_Bluetooth_Setup_Screen
        storageSpace_OCR_Setup_Screen
        storageSpace_Manual_Controller_Screen
        storageSpace_OCR_Controller_Screen
        storageSpace_NE_Error_Controller_Screen
        storageSpace_Pause_Menu_Screen
        
        % Allows for UI control requiring knowledge of currently and previously active screens
        Previous_Screen
        Current_Screen
        
        
        % These are the properties that hold the settings desired by the user
        requested_Use_Bluetooth_Communication
        requested_Gather_OCR_Data
        requested_Show_Commands_Sent
        requested_Enter_Commands_Manually
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%% NON-GRAPHICAL PROPERTIES %%%%%%%%%%
        
%         faultyErrorReadings
        myOCR
        myB
        commandCenter
        
        bluetooth_SetupComplete
        OCR_SetupComplete
        
        command_StopRobot
        
        previousCommand
        
        cmd
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % properties set up during initialization of user defined settings
        
        ACTION1_startStopped
        ACTION1_command
        
        ACTION2_startStopped
        ACTION2_command
        
        ACTION3_startStopped
        ACTION3_command
        
        ACTION4_startStopped
        ACTION4_command
        
        motionTimer             % Timer used to have robot move for a given amount of time 
        motionTimer_isRunning   % Flag for motionTimer (1 - robot movement time being controlled).
        
        pauseTimer              % Timer used to have robot pause for a given amount of time 
        pauseTimer_isRunning    % Flag for pauseTimer (1 - robot pause time being controlled).
        pauseTimer_requested
        
        delayCommands           % flag set if either motionTimer or pauseTimer is running
        
        
        isReadyToStamp
        markingCommandSent
        stampDelayTimer_isRunning
        stampDelayTimer
        
        pulseMotionSpeed
        
    end

    methods (Access = private)
    
        function sendCommandsUsingOCR(app)
            
            app.command_StopRobot = 0;
            app.cmd = 'not a valid command yet';
            faultyErrorReadings = 0;
            
            app.delayCommands = 0;
            app.pauseTimer_requested = 0;
            
            app.pauseTimer_isRunning = 0;
            stop(app.pauseTimer);
            app.motionTimer_isRunning = 0;
            stop(app.motionTimer);
            
            app.markingCommandSent = 0;
            app.isReadyToStamp = 0;
            app.stampDelayTimer_isRunning = 0;
            stop(app.stampDelayTimer);
            
            while (~app.markingCommandSent && (app.command_StopRobot == 0))
%             while (app.command_StopRobot == 0)
                
                [faultyErrorReadings, errorN, errorE] = app.myOCR.getErrorData();
                
                
                % errorN and errorE should be valid
                if (faultyErrorReadings == 0 || app.pauseTimer_requested)
                    
                    if (~app.delayCommands)
                    
                        % This flag is only set once motionTimer finishes executing
                        if (app.pauseTimer_requested)
                            app.pauseTimer_requested = 0;
                            
                            app.delayCommands = 1;
                            
                            app.pauseTimer_isRunning = 1;
                            start(app.pauseTimer);
                            
    %                         disp('pauseTimer is started')
                            
                            app.cmd = 's';
                        
                        else
                            % get the command needed to control the robot
                            app.cmd = app.commandCenter.retrieveCommand(errorN, errorE);
                            
                            % If the robot is in the correct location to mark and wants to do so
                            if (strcmp(app.cmd,'x'))
                                % if the robot is verifiably in the correct location to stamp
                                if (app.isReadyToStamp)
                                    app.isReadyToStamp = 0;
                                    % continue to sending of the marking command
                                % if the robot just arrived at the location to stamp
                                elseif (~app.stampDelayTimer_isRunning)
                                    app.stampDelayTimer_isRunning = 1;
                                    start(app.stampDelayTimer);
                                    app.cmd = 's';
                                % if the robot is still in the correct place to stamp but needs to wait a little longer
                                else
                                    pause(.01);
                                    continue;
                                end
                            % if the robot is not potentially recieving the marking command
                            else
                                % if the robot was in the desired location for stamping but then left it
                                if (app.stampDelayTimer_isRunning)
                                    stop(app.stampDelayTimer);
                                    app.stampDelayTimer_isRunning = 0;
                                end
                                % If slowest speed is detected commands will be sent to robot for it to move and then pause
                                %   until the robot arrives at its destination.
                                if (app.cmd(1) == '0')
                                    app.delayCommands = 1;
                                    
                                    app.motionTimer_isRunning = 1;
                                    start(app.motionTimer);
                                    
                                    app.cmd(1) = app.pulseMotionSpeed;
                                end
                            end
                        
                        end
                        
                        % if a marking command is actually being sent out to the robot
                        if (strcmp(app.cmd,'x'))
                            app.markingCommandSent = 1;
                        end
                        
                        % if chosen to send commands to robot
                        if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                            try
                                app.myB.send_commands(app.cmd);
                            catch
                                
                            end
                        end
                       
                        % If user wants to see ocr commands on matlab command window
                        if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                            app.OCR_Controller_Screen_LastCommandSent.Text = app.cmd;
                        end
                    
    %                 else
    %                     disp('In timer!')
                    end
                    
                    
                    % Update displayed northing and easting errors
                    app.updatedNorthingErrorLabel.Text = num2str(errorN);
                    app.updatedEastingErrorLabel.Text = num2str(errorE);
                    % Update displayed current desired offset
                    app.updatedDesiredOffsetLabel.Text = num2str(app.commandCenter.getNextOffsetToDestination());


                % readings from ocr are unusable
                else
%                     disp(['faulty error readings received'])
%                     disp('')
%                     faultyErrorReadings = 0;
            
                end
                pause(.01);
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function sendCommandsUsingManualNEError(app)
            
            app.command_StopRobot = 0;
            app.cmd = 'not a valid command yet';
            
            app.delayCommands = 0;
            app.pauseTimer_requested = 0;
            
            app.pauseTimer_isRunning = 0;
            stop(app.pauseTimer);
            app.motionTimer_isRunning = 0;
            stop(app.motionTimer);
            
            app.markingCommandSent = 0;
            app.isReadyToStamp = 0;
            app.stampDelayTimer_isRunning = 0;
            stop(app.stampDelayTimer);
            
            
            while (~app.markingCommandSent && (app.command_StopRobot == 0))
%             while (app.command_StopRobot == 0)
                try
                    errorN = app.NE_Error_Controller_Screen_NorthingErrorInput.Value;
                    errorE = app.NE_Error_Controller_Screen_EastingErrorInput.Value;
                catch
                    continue;
                end


                if (~app.delayCommands)
                
                    % This flag is only set once motionTimer finishes executing
                    if (app.pauseTimer_requested)
                        app.pauseTimer_requested = 0;
                        
                        app.delayCommands = 1;
                        
                        app.pauseTimer_isRunning = 1;
                        start(app.pauseTimer);
                        
%                         disp('pauseTimer is started')
                        
                        app.cmd = 's';
                    
                    else
                        % get the command needed to control the robot
                        app.cmd = app.commandCenter.retrieveCommand(errorN, errorE);
                        
                        % If the robot is in the correct location to mark and wants to do so
                        if (strcmp(app.cmd,'x'))
                            % if the robot is verifiably in the correct location to stamp
                            if (app.isReadyToStamp)
                                app.isReadyToStamp = 0;
                                % continue to sending of the marking command
                            % if the robot just arrived at the location to stamp
                            elseif (~app.stampDelayTimer_isRunning)
                                app.stampDelayTimer_isRunning = 1;
                                start(app.stampDelayTimer);
                                app.cmd = 's';
                            % if the robot is still in the correct place to stamp but needs to wait a little longer
                            else
                                pause(.01);
                                continue;
                            end
                        % if the robot is not potentially recieving the marking command
                        else
                            % if the robot was in the desired location for stamping but then left it
                            if (app.stampDelayTimer_isRunning)
                                stop(app.stampDelayTimer);
                                app.stampDelayTimer_isRunning = 0;
                            end
                            % If slowest speed is detected commands will be sent to robot for it to move and then pause
                            %   until the robot arrives at its destination.
                            if (app.cmd(1) == '0')
                                app.delayCommands = 1;
                                
                                app.motionTimer_isRunning = 1;
                                start(app.motionTimer);
                                
                                app.cmd(1) = app.pulseMotionSpeed;
                            end
                        end
                    
                    end
                    
                    % if a marking command is actually being sent out to the robot
                    if (strcmp(app.cmd,'x'))
                        app.markingCommandSent = 1;
                    end
                    
                    % if chosen to send commands to robot
                    if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                        try
                            app.myB.send_commands(app.cmd);
                        catch
                            
                        end
                    end
                   
                    % If user wants to see ocr commands on matlab command window
                    if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                        app.NE_Error_Controller_Screen_LastCommandSent.Text = app.cmd;
                    end
                
%                 else
%                     disp('In timer!')
                end
        
                    
                pause(.01);
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function init_user_defined_properties(app)
            fileID = fopen('user_defined_app_properties__manualController.txt','r');

            C = textscan(fileID,'%d %d %s %s','Delimiter',',',...
            'TreatAsEmpty',{'NA','na'},'CommentStyle','%');
            
            fclose(fileID);
            
            isButtonEnabled = C{1};
            isStopRequired = C{2};
            buttonLabels = (C{3});
            pushbuttonCommands = (C{4});
            
            
            % Set up the ACTION1 button in manual control mode
            app.Manual_Controller_Screen_ACTION1Button.Enable = isButtonEnabled(1);
            app.ACTION1_startStopped = isStopRequired(1);
            app.Manual_Controller_Screen_ACTION1Button.Text = buttonLabels(1);
            app.ACTION1_command = pushbuttonCommands(1);
            
            % Set up the ACTION2 button in manual control mode
            app.Manual_Controller_Screen_ACTION2Button.Enable = isButtonEnabled(2);
            app.ACTION2_startStopped = isStopRequired(2);
            app.Manual_Controller_Screen_ACTION2Button.Text = buttonLabels(2);
            app.ACTION2_command = pushbuttonCommands(2);
            
            % Set up the ACTION3 button in manual control mode
            app.Manual_Controller_Screen_ACTION3Button.Enable = isButtonEnabled(3);
            app.ACTION3_startStopped = isStopRequired(3);
            app.Manual_Controller_Screen_ACTION3Button.Text = buttonLabels(3);
            app.ACTION3_command = pushbuttonCommands(3);
            
            % Set up the ACTION4 button in manual control mode
            app.Manual_Controller_Screen_ACTION4Button.Enable = isButtonEnabled(4);
            app.ACTION4_startStopped = isStopRequired(4);
            app.Manual_Controller_Screen_ACTION4Button.Text = buttonLabels(4);
            app.ACTION4_command = pushbuttonCommands(4);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % update speed differences from given distances from destination and error permitted
            fileID = fopen('speed2ErrorANDerrorPermitted.txt','r');

            C = textscan(fileID,'%f','Delimiter',',',...
            'TreatAsEmpty',{'NA','na'},'CommentStyle','%');
            
            fclose(fileID);
            
            userInputs = C{1};
            
            speed1LowerBound = userInputs(1);
            speed2LowerBound = userInputs(2);
            speed3LowerBound = userInputs(3);
            errorsPermitted = [userInputs(4), userInputs(5), userInputs(6), userInputs(7)];
            
            app.commandCenter.updateSpeedLocations(speed1LowerBound, speed2LowerBound, speed3LowerBound);
            app.commandCenter.updateErrorPermitted(errorsPermitted);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % update time intervals of movement and pauses for motion of robot near destination
            fileID = fopen('slowSpeedControl_timeIntervals.txt','r');

            C = textscan(fileID,'%f','Delimiter',',',...
            'TreatAsEmpty',{'NA','na'},'CommentStyle','%');
            
            fclose(fileID);
            
            userInputs = C{1};
            
            app.motionTimer.StartDelay = userInputs(1);
            app.pauseTimer.StartDelay = userInputs(2);
            app.stampDelayTimer.StartDelay = userInputs(3);
            app.pulseMotionSpeed = num2str(userInputs(4));
            
        end
        
        function motionTimer_TimerFcn(app,src,event)
            app.delayCommands = 0; 
            app.motionTimer_isRunning = 0; 
            app.pauseTimer_requested = 1;
        end
        
        function pauseTimer_TimerFcn(app,src,event)
            app.delayCommands = 0; 
            app.pauseTimer_isRunning = 0;
        end
        
        function stampDelayTimer_TimerFcn(app,src,event)
%             if (strcmp(app.cmd,'x') && app.stampDelayTimer_isRunning) %strcmp(app.stampDelayTimer.Running,'on'))
%                 app.isReadyToStamp = 1;
%             end
            app.isReadyToStamp = 1;
            app.stampDelayTimer_isRunning = 0;
        end  
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%% GRAPHICAL PROPERTIES %%%%%%%%%%%%
            
            app.OCR_Setup_Screen_UIAxes.Visible = 'off';
            app.OCR_Controller_Screen_UIAxes.Visible = 'off';
            
            % These are the values of the active screen's position 
            app.openPanel_position = [1 1 507 415];
            
            % Save the locations where unused screens will be stored
            app.storageSpace_Welcome_Screen = app.Welcome_Screen.Position;
            app.storageSpace_Settings_Screen = app.BlankScreenTemplatePanel.Position;
            app.storageSpace_Bluetooth_Setup_Screen = app.Settings_Screen.Position;
            app.storageSpace_OCR_Setup_Screen = app.OCR_Setup_Screen.Position;
            app.storageSpace_Manual_Controller_Screen = app.Manual_Controller_Screen.Position;
            app.storageSpace_OCR_Controller_Screen = app.OCR_Controller_Screen.Position;
            app.storageSpace_NE_Error_Controller_Screen = app.NE_Error_Controller_Screen.Position;
            app.storageSpace_Pause_Menu_Screen = app.Pause_Menu_Screen.Position;
            
            
            % Set Previous_Screen and Current_Screen to the Welcome Screen
            app.Previous_Screen = app.Welcome_Screen_Title.Text;
            app.Current_Screen = app.Welcome_Screen_Title.Text;
            
            % show the robot rendering on opening screen
            imshow('render1.JPG','Parent',app.Welcome_Screen_UIAxes)
            
            % Make the welcome screen be the active screen
            % This should be done after the image in the welcome screen is uploaded
            app.Welcome_Screen.Position = app.openPanel_position;
       
            app.Bluetooth_Setup_Screen_ConnectMessage.Visible = 'off';
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%% NON-GRAPHICAL PROPERTIES %%%%%%%%%%
            
%             app.faultyErrorReadings = 0;
            app.myOCR = OCR_errorInterpereter();
            app.myB = BluetoothTransmitter();
            app.commandCenter = Robot_CommandCompiler();
            
            app.bluetooth_SetupComplete = false;
            app.OCR_SetupComplete = false;
            app.command_StopRobot = 1;
            
            
            % Instead of enabling this when user desires to use the ocr to control,
            %   this button will now just send the user to the settings menu if the ocr is not set.
%             app.Manual_Controller_Screen_BACKTOOCRCONTROLButton.Enable = 0;
           
            
            app.delayCommands = 0;
            
            app.motionTimer_isRunning = 0;
            app.motionTimer = timer('TimerFcn', @app.motionTimer_TimerFcn,... 
                'StartDelay',0.5);
            
            app.pauseTimer_isRunning = 0;
            app.pauseTimer  = timer('TimerFcn', @app.pauseTimer_TimerFcn,... 
                'StartDelay',1);
            
            app.pauseTimer_requested = 0;           

            
            app.stampDelayTimer_isRunning = 0;
            app.stampDelayTimer  = timer('TimerFcn', @app.stampDelayTimer_TimerFcn,... 
                'StartDelay',2);
            
            app.isReadyToStamp = 0;
            app.markingCommandSent = 0;


            app.init_user_defined_properties();
            
        end

        % Callback function
        function Panel_3SizeChanged(app, event)
        
        end

        % Button pushed function: Welcome_Screen_STARTButton
        function Welcome_Screen_STARTButtonPushed(app, event)
            app.Welcome_Screen.Position = app.storageSpace_Welcome_Screen;
            app.Settings_Screen.Position = app.openPanel_position;
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = app.Settings_Screen_Title.Text;
        end

        % Button pushed function: Settings_Screen_CONTINUEButton
        function Settings_Screen_CONTINUEButtonPushed(app, event)
            
            newCurrentScreen = '';
            
            app.Settings_Screen.Position = app.storageSpace_Settings_Screen;
            
            % What will happen once the continue button is pressed based on user's preferred settings
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                % Going next to Bluetooth_Setup_Screen
                app.Bluetooth_Setup_Screen.Position = app.openPanel_position;
                newCurrentScreen = app.Bluetooth_Setup_Screen_Title.Text;
                app.Bluetooth_Setup_Screen_ConnectMessage.Visible = 'off';
            elseif (app.Settings_Screen_UseOCRToGatherDataCheckBox.Value)
                % Going next to OCR_Setup_Screen
                app.OCR_Setup_Screen.Position = app.openPanel_position;
                newCurrentScreen = app.OCR_Setup_Screen_Title.Text;
            elseif (app.Settings_Screen_EnterCommandsManuallyCheckBox.Value)
                % Going next to Manual_Controller_Screen
                app.Manual_Controller_Screen.Position = app.openPanel_position;
                newCurrentScreen = app.Manual_Controller_Screen_Title.Text;
            else
                % Going next to NE_Error_Controller_Screen
                app.NE_Error_Controller_Screen.Position = app.openPanel_position;
                newCurrentScreen = app.NE_Error_Controller_Screen_Title.Text;
            end
            
            % Make command readings location only visible if that feature is desired by user
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent_Panel.Visible = 'on';
                app.OCR_Controller_Screen_LastCommandSent_Panel.Visible = 'on';
                app.NE_Error_Controller_Screen_LastCommandSent_Panel.Visible = 'on';
            else 
                app.Manual_Controller_Screen_LastCommandSent_Panel.Visible = 'off';
                app.OCR_Controller_Screen_LastCommandSent_Panel.Visible = 'off';
                app.NE_Error_Controller_Screen_LastCommandSent_Panel.Visible = 'off';
            end
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = newCurrentScreen;
            
        end

        % Button pushed function: 
        % Bluetooth_Setup_Screen_GOBACKTOSETTINGSButton
        function Bluetooth_Setup_Screen_GOBACKTOSETTINGSButtonPushed(app, event)
            app.Bluetooth_Setup_Screen.Position = app.storageSpace_Bluetooth_Setup_Screen;
            app.Settings_Screen.Position = app.openPanel_position;
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = app.Settings_Screen_Title.Text;
        end

        % Button pushed function: 
        % Bluetooth_Setup_Screen_CONTINUEButton
        function Bluetooth_Setup_Screen_CONTINUEButtonPushed(app, event)
            if (app.bluetooth_SetupComplete)
                newCurrentScreen = '';
                
                app.Bluetooth_Setup_Screen.Position = app.storageSpace_Bluetooth_Setup_Screen;
                
                % What will happen once the continue button is pressed based on user's preferred settings
                if (app.Settings_Screen_UseOCRToGatherDataCheckBox.Value)
                    % Going next to OCR_Setup_Screen
                    app.OCR_Setup_Screen.Position = app.openPanel_position;
                    newCurrentScreen = app.OCR_Setup_Screen_Title.Text;
                elseif (app.Settings_Screen_EnterCommandsManuallyCheckBox.Value)
                    % Going next to Manual_Controller_Screen
                    app.Manual_Controller_Screen.Position = app.openPanel_position;
                    newCurrentScreen = app.Manual_Controller_Screen_Title.Text;
                else
                    % Going next to NE_Error_Controller_Screen
                    app.NE_Error_Controller_Screen.Position = app.openPanel_position;
                    newCurrentScreen = app.NE_Error_Controller_Screen_Title.Text;
                end
                
                % Update Previous_Screen and Current_Screen
                app.Previous_Screen = app.Current_Screen;
                app.Current_Screen = newCurrentScreen;
            end
        end

        % Button pushed function: 
        % OCR_Setup_Screen_GOBACKTOSETTINGSButton
        function OCR_Setup_Screen_GOBACKTOSETTINGSButtonPushed(app, event)
            app.OCR_Setup_Screen.Position = app.storageSpace_OCR_Setup_Screen;
            app.Settings_Screen.Position = app.openPanel_position;
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = app.Settings_Screen_Title.Text;
        end

        % Button pushed function: OCR_Setup_Screen_CONTINUEButton
        function OCR_Setup_Screen_CONTINUEButtonPushed(app, event)
            if (app.OCR_SetupComplete)
                app.OCR_Setup_Screen.Position = app.storageSpace_OCR_Setup_Screen;
                app.OCR_Controller_Screen.Position = app.openPanel_position;
                
                % Update Previous_Screen and Current_Screen
                app.Previous_Screen = app.Current_Screen;
                app.Current_Screen = app.OCR_Controller_Screen_Title.Text;
            end
        end

        % Button pushed function: 
        % Pause_Menu_Screen_RETURNTOPREVIOUSSCREENButton
        function Pause_Menu_Screen_RETURNTOPREVIOUSSCREENButtonPushed(app, event)
            app.Pause_Menu_Screen.Position = app.storageSpace_Pause_Menu_Screen;
            
            if (strcmp(app.Previous_Screen,app.Manual_Controller_Screen_Title.Text))
                app.Manual_Controller_Screen.Position = app.openPanel_position;
            elseif (strcmp(app.Previous_Screen,app.OCR_Controller_Screen_Title.Text))
                app.OCR_Controller_Screen.Position = app.openPanel_position;
            elseif (strcmp(app.Previous_Screen,app.NE_Error_Controller_Screen_Title.Text))
                app.NE_Error_Controller_Screen.Position = app.openPanel_position;
            end
            
            % Update Previous_Screen and Current_Screen
            tempHolder_Previous_Screen = app.Previous_Screen;
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = tempHolder_Previous_Screen;
        end

        % Button pushed function: Pause_Menu_Screen_GOTOSETTINGSButton
        function Pause_Menu_Screen_GOTOSETTINGSButtonPushed(app, event)
            app.Pause_Menu_Screen.Position = app.storageSpace_Pause_Menu_Screen;
            app.Settings_Screen.Position = app.openPanel_position;
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = app.Settings_Screen_Title.Text;
        end

        % Button pushed function: Pause_Menu_Screen_QUITButton
        function Pause_Menu_Screen_QUITButtonPushed(app, event)
            app.Pause_Menu_Screen.Position = app.storageSpace_Pause_Menu_Screen;
            app.Welcome_Screen.Position = app.openPanel_position;
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = app.Welcome_Screen_Title.Text;
        end

        % Button pushed function: Manual_Controller_Screen_PAUSEButton
        function Manual_Controller_Screen_PAUSEButtonPushed(app, event)
            app.Manual_Controller_Screen.Position = app.storageSpace_Manual_Controller_Screen;
            app.Pause_Menu_Screen.Position = app.openPanel_position;
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = app.Pause_Menu_Screen_Title.Text;
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Just in case, send a stop command to the robot
            app.command_StopRobot = 1;
            
            app.cmd = 's';
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: OCR_Controller_Screen_PAUSEButton
        function OCR_Controller_Screen_PAUSEButtonPushed(app, event)
            app.OCR_Controller_Screen.Position = app.storageSpace_OCR_Controller_Screen;
            app.Pause_Menu_Screen.Position = app.openPanel_position;
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = app.Pause_Menu_Screen_Title.Text;
            
            
            % Clear displayed northing and easting errors
            app.updatedNorthingErrorLabel.Text = '';
            app.updatedEastingErrorLabel.Text = '';
            % Clear displayed current desired offset
            app.updatedDesiredOffsetLabel.Text = '';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Just in case, send a stop command to the robot
            app.command_StopRobot = 1;
            
            app.cmd = 's';
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.OCR_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: 
        % NE_Error_Controller_Screen_PAUSEButton
        function NE_Error_Controller_Screen_PAUSEButtonPushed(app, event)
            app.NE_Error_Controller_Screen.Position = app.storageSpace_Manual_Controller_Screen;
            app.Pause_Menu_Screen.Position = app.openPanel_position;
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = app.Pause_Menu_Screen_Title.Text;
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Just in case, send a stop command to the robot
            app.command_StopRobot = 1;
            
            app.cmd = 's';
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.NE_Error_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Value changed function: 
        % Settings_Screen_UseOCRToGatherDataCheckBox
        function Settings_Screen_UseOCRToGatherDataCheckBoxValueChanged(app, event)
            value = app.Settings_Screen_UseOCRToGatherDataCheckBox.Value;
            
            if (value)
                app.Settings_Screen_EnterCommandsManuallyCheckBox.Value = 0;
                app.Settings_Screen_ControlUsingNEErrorCheckBox.Value = 0;
            end
        end

        % Value changed function: 
        % Settings_Screen_EnterCommandsManuallyCheckBox
        function Settings_Screen_EnterCommandsManuallyCheckBoxValueChanged(app, event)
            value = app.Settings_Screen_EnterCommandsManuallyCheckBox.Value;
                        
            if (value)
                app.Settings_Screen_UseOCRToGatherDataCheckBox.Value = 0;
                app.Settings_Screen_ControlUsingNEErrorCheckBox.Value = 0;
            end
        end

        % Value changed function: 
        % Settings_Screen_ControlUsingNEErrorCheckBox
        function Settings_Screen_ControlUsingNEErrorCheckBoxValueChanged(app, event)
            value = app.Settings_Screen_ControlUsingNEErrorCheckBox.Value;
                        
            if (value)
                app.Settings_Screen_UseOCRToGatherDataCheckBox.Value = 0;
                app.Settings_Screen_EnterCommandsManuallyCheckBox.Value = 0;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             if (app.myB.isConnected())
            try
                app.myB.disconnectFromDevice();
            catch
                
            end
            
            stop(timerfindall)
            delete(timerfindall)
            
            delete(app)
        end

        % Button pushed function: Bluetooth_Setup_Screen_CONNECTButton
        function Bluetooth_Setup_Screen_CONNECTButtonPushed(app, event)
            
%             app.Bluetooth_Setup_Screen_ConnectMessage.Visible = 'off';
%             app.Bluetooth_Setup_Screen_ConnectMessage.Text = 'Trying to connect to device';
            app.Bluetooth_Setup_Screen_ConnectMessage.FontColor = 'b';
            app.Bluetooth_Setup_Screen_ConnectMessage.Visible = 'on';
            
            app.Bluetooth_Setup_Screen_DeviceConnectedLamp.Color = [0.65,0.65,0.65];
%             app.Bluetooth_Setup_Screen_ConnectMessage.FontColor = 'b';
%             app.Bluetooth_Setup_Screen_ConnectMessage.Text = 'Trying to connect to device';
%             app.Bluetooth_Setup_Screen_ConnectMessage.Visible = 'on';
            pause(.1);
            try
%                 app.Bluetooth_Setup_Screen_ConnectMessage.Visible = 'off';
                
                app.myB.connectToDevice(app.Bluetooth_Setup_Screen_UserInput.Value);
            catch
%                 app.Bluetooth_Setup_Screen_ConnectMessage.Visible = 'off';
%                 app.Bluetooth_Setup_Screen_ConnectMessage.FontColor = 'r';
%                 app.Bluetooth_Setup_Screen_ConnectMessage.Text = 'error while connecting';
%                 app.Bluetooth_Setup_Screen_ConnectMessage.Visible = 'on';
                
                app.Bluetooth_Setup_Screen_ConnectMessage.Visible = 'off';

                % Turn lamp red to show that connection did not work
                app.Bluetooth_Setup_Screen_DeviceConnectedLamp.Color = 'r';
                return;    
            end
            
            app.Bluetooth_Setup_Screen_ConnectMessage.Visible = 'off';
            
            app.Bluetooth_Setup_Screen_DeviceConnectedLamp.Color = 'g';
            app.bluetooth_SetupComplete = true;
        end

        % Button pushed function: 
        % OCR_Setup_Screen_SelectOCRReadLocationButton
        function OCR_Setup_Screen_SelectOCRReadLocationButtonPushed(app, event)
            try
                app.myOCR.updateRect();
            catch
                return;
            end
            app.OCR_SetupComplete = true;
        end

        % Button pushed function: 
        % OCR_Setup_Screen_PreviewSelectionButton
        function OCR_Setup_Screen_PreviewSelectionButtonPushed(app, event)
            try
%                 app.myOCR.croppedImage = screencapture(0, app.myOCR.rect);
                  app.myOCR.croppedImage = Screen_Capture(app.myOCR.rect);
            catch
                return;
            end
            
            
            % show the robot rendering on opening screen
            imshow(app.myOCR.croppedImage,'Parent',app.OCR_Setup_Screen_UIAxes)
%             app.OCR_Setup_Screen_UIAxes.Visible = 'on';
        end

        % Button pushed function: OCR_Controller_Screen_EnableCommands
        function OCR_Controller_Screen_EnableCommandsButtonPushed(app, event)
            sendCommandsUsingOCR(app);
        end

        % Button pushed function: 
        % OCR_Controller_Screen_STOPROBOTButton
        function OCR_Controller_Screen_STOPROBOTButtonPushed(app, event)
            
            app.command_StopRobot = 1;
            
            app.cmd = 's';
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.OCR_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
            
        end

        % Button pushed function: 
        % NE_Error_Controller_Screen_SendCommandButton
        function NE_Error_Controller_Screen_SendCommandButtonPushed(app, event)
            sendCommandsUsingManualNEError(app);
        end

        % Button pushed function: 
        % NE_Error_Controller_Screen_STOPROBOTButton
        function NE_Error_Controller_Screen_STOPROBOTButtonPushed(app, event)
                        
            app.command_StopRobot = 1;
            
            app.cmd = 's';
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.NE_Error_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
            
        end

        % Button pushed function: 
        % Manual_Controller_Screen_FORWARDButton
        function Manual_Controller_Screen_FORWARDButtonPushed(app, event)
            app.command_StopRobot = 0;
            
            if (app.Manual_Controller_Screen_FastButton.Value)
                app.cmd = '3n';
            elseif (app.Manual_Controller_Screen_NormalButton.Value)
                app.cmd = '2n';
            else
                app.cmd = '1n';
            end
            
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: 
        % Manual_Controller_Screen_REVERSEButton
        function Manual_Controller_Screen_REVERSEButtonPushed(app, event)
            app.command_StopRobot = 0;
            
            if (app.Manual_Controller_Screen_FastButton.Value)
                app.cmd = '3s';
            elseif (app.Manual_Controller_Screen_NormalButton.Value)
                app.cmd = '2s';
            else
                app.cmd = '1s';
            end
            
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: Manual_Controller_Screen_RIGHTButton
        function Manual_Controller_Screen_RIGHTButtonPushed(app, event)
            app.command_StopRobot = 0;
            
            if (app.Manual_Controller_Screen_FastButton.Value)
                app.cmd = '3e';
            elseif (app.Manual_Controller_Screen_NormalButton.Value)
                app.cmd = '2e';
            else
                app.cmd = '1e';
            end
            
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: Manual_Controller_Screen_LEFTButton
        function Manual_Controller_Screen_LEFTButtonPushed(app, event)
            app.command_StopRobot = 0;
            
            if (app.Manual_Controller_Screen_FastButton.Value)
                app.cmd = '3w';
            elseif (app.Manual_Controller_Screen_NormalButton.Value)
                app.cmd = '2w';
            else
                app.cmd = '1w';
            end
            
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: Manual_Controller_Screen_STOPButton
        function Manual_Controller_Screen_STOPButtonPushed(app, event)
            app.command_StopRobot = 1;
           
            app.cmd = 's';
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: Manual_Controller_Screen_MARKButton
        function Manual_Controller_Screen_MARKButtonPushed(app, event)
            if (app.command_StopRobot == 1)
               
                app.cmd = 'x';
                
                % if chosen to send commands to robot
                if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                    try
                        app.myB.send_commands(app.cmd);
                    catch
                        
                    end
                end
                
                % If user wants to see ocr commands on matlab command window
                if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                    app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
                end
                
            end
        end

        % Button pushed function: 
        % Manual_Controller_Screen_ROTATELEFTButton
        function Manual_Controller_Screen_ROTATELEFTButtonPushed(app, event)
            app.command_StopRobot = 0;
            
            if (app.Manual_Controller_Screen_FastButton.Value)
                app.cmd = '3cc';
            elseif (app.Manual_Controller_Screen_NormalButton.Value)
                app.cmd = '2cc';
            else
                app.cmd = '1cc';
            end
            
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end            
        end

        % Button pushed function: 
        % Manual_Controller_Screen_ROTATERIGHTButton
        function Manual_Controller_Screen_ROTATERIGHTButtonPushed(app, event)
            app.command_StopRobot = 0;
            
            if (app.Manual_Controller_Screen_FastButton.Value)
                app.cmd = '3c';
            elseif (app.Manual_Controller_Screen_NormalButton.Value)
                app.cmd = '2c';
            else
                app.cmd = '1c';
            end
            
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: Manual_Controller_Screen_KILLButton
        function Manual_Controller_Screen_KILLButtonPushed(app, event)
            app.command_StopRobot = 1;
           
            app.cmd = 's';
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end            
        end

        % Button pushed function: 
        % OCR_Controller_Screen_PreviewSelectionButton
        function OCR_Controller_Screen_PreviewSelectionButtonPushed(app, event)
            try
%                 app.myOCR.croppedImage = screencapture(0, app.myOCR.rect);
                app.myOCR.croppedImage = Screen_Capture(app.myOCR.rect);
            catch
                return;
            end
            
            
            % show the robot rendering on opening screen
            imshow(app.myOCR.croppedImage,'Parent',app.OCR_Controller_Screen_UIAxes)
%             app.OCR_Setup_Screen_UIAxes.Visible = 'on';
        end

        % Button pushed function: 
        % OCR_Controller_Screen_AdjustOCRButton
        function OCR_Controller_Screen_AdjustOCRButtonPushed(app, event)
            
            app.OCR_Controller_Screen.Position = app.storageSpace_OCR_Controller_Screen;
            app.OCR_Setup_Screen.Position = app.openPanel_position;
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = app.OCR_Setup_Screen_Title.Text;
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Just in case, send a stop command to the robot
            app.command_StopRobot = 1;
            
            app.cmd = 's';
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.OCR_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: 
        % Manual_Controller_Screen_ACTION1Button
        function Manual_Controller_Screen_ACTION1ButtonPushed(app, event)
            if (app.ACTION1_startStopped)  
                if (app.command_StopRobot == 0)
                    return;
                end
            end
            
            app.cmd = app.ACTION1_command;
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
            
            
        end

        % Button pushed function: 
        % Manual_Controller_Screen_ACTION2Button
        function Manual_Controller_Screen_ACTION2ButtonPushed(app, event)
            if (app.ACTION2_startStopped)  
                if (app.command_StopRobot == 0)
                    return;
                end
            end
            
            app.cmd = app.ACTION2_command;
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
            
            
        end

        % Button pushed function: Manual_Controller_Screen_NWButton
        function Manual_Controller_Screen_NWButtonPushed(app, event)
            app.command_StopRobot = 0;
            
            if (app.Manual_Controller_Screen_FastButton.Value)
                app.cmd = '3nw';
            elseif (app.Manual_Controller_Screen_NormalButton.Value)
                app.cmd = '2nw';
            else
                app.cmd = '1nw';
            end
            
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: Manual_Controller_Screen_NEButton
        function Manual_Controller_Screen_NEButtonPushed(app, event)
            app.command_StopRobot = 0;
            
            if (app.Manual_Controller_Screen_FastButton.Value)
                app.cmd = '3ne';
            elseif (app.Manual_Controller_Screen_NormalButton.Value)
                app.cmd = '2ne';
            else
                app.cmd = '1ne';
            end
            
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: Manual_Controller_Screen_SWButton
        function Manual_Controller_Screen_SWButtonPushed(app, event)
            app.command_StopRobot = 0;
            
            if (app.Manual_Controller_Screen_FastButton.Value)
                app.cmd = '3sw';
            elseif (app.Manual_Controller_Screen_NormalButton.Value)
                app.cmd = '2sw';
            else
                app.cmd = '1sw';
            end
            
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: Manual_Controller_Screen_SEButton
        function Manual_Controller_Screen_SEButtonPushed(app, event)
            app.command_StopRobot = 0;
            
            if (app.Manual_Controller_Screen_FastButton.Value)
                app.cmd = '3se';
            elseif (app.Manual_Controller_Screen_NormalButton.Value)
                app.cmd = '2se';
            else
                app.cmd = '1se';
            end
            
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Selection changed function: 
        % Manual_Controller_Screen_MOVEMENTSPEEDButtonGroup
        function Manual_Controller_Screen_MOVEMENTSPEEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.Manual_Controller_Screen_MOVEMENTSPEEDButtonGroup.SelectedObject;
            
           
            % If robot is moving
            if (app.command_StopRobot == 0)
                
                if (app.Manual_Controller_Screen_FastButton.Value)
                    app.cmd = ['3',extractAfter(app.cmd,1)];
                elseif (app.Manual_Controller_Screen_NormalButton.Value)
                    app.cmd = ['2',extractAfter(app.cmd,1)];
                else
                    app.cmd = ['1',extractAfter(app.cmd,1)];
                end
                
                
                % if chosen to send commands to robot
                if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                    try
                        app.myB.send_commands(app.cmd);
                    catch
                        
                    end
                end
                
                % If user wants to see ocr commands on matlab command window
                if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                    app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
                end
                
            end
                    
           
        end

        % Button pushed function: 
        % Manual_Controller_Screen_ACTION3Button
        function Manual_Controller_Screen_ACTION3ButtonPushed(app, event)
            if (app.ACTION3_startStopped)  
                if (app.command_StopRobot == 0)
                    return;
                end
            end
            
            app.cmd = app.ACTION3_command;
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
            
            
        end

        % Button pushed function: 
        % Manual_Controller_Screen_ACTION4Button
        function Manual_Controller_Screen_ACTION4ButtonPushed(app, event)
            if (app.ACTION4_startStopped)  
                if (app.command_StopRobot == 0)
                    return;
                end
            end
            
            app.cmd = app.ACTION4_command;
            
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.Manual_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
            
            
        end

        % Button pushed function: 
        % OCR_Controller_Screen_MANUALOVERRIDEButton
        function OCR_Controller_Screen_MANUALOVERRIDEButtonPushed(app, event)

            
            app.OCR_Controller_Screen.Position = app.storageSpace_OCR_Controller_Screen;
            app.Manual_Controller_Screen.Position = app.openPanel_position;
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = app.Manual_Controller_Screen_Title.Text;
            
            
            % Clear displayed northing and easting errors
            app.updatedNorthingErrorLabel.Text = '';
            app.updatedEastingErrorLabel.Text = '';
            % Clear displayed current desired offset
            app.updatedDesiredOffsetLabel.Text = '';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Just in case, send a stop command to the robot
            app.command_StopRobot = 1;
            
            app.cmd = 's';
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.OCR_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end

        % Button pushed function: 
        % Manual_Controller_Screen_BACKTOOCRCONTROLButton
        function Manual_Controller_Screen_BACKTOOCRCONTROLButtonPushed(app, event)
            
            
            app.Manual_Controller_Screen.Position = app.storageSpace_Manual_Controller_Screen;
            
            if (app.Settings_Screen_UseOCRToGatherDataCheckBox.Value)
                app.OCR_Controller_Screen.Position = app.openPanel_position;
            else
                 app.Settings_Screen.Position = app.openPanel_position;
            end
            
            % Update Previous_Screen and Current_Screen
            app.Previous_Screen = app.Current_Screen;
            app.Current_Screen = app.OCR_Controller_Screen_Title.Text;
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Just in case, send a stop command to the robot
            app.command_StopRobot = 1;
            
            app.cmd = 's';
            % if chosen to send commands to robot
            if (app.Settings_Screen_CommunicateWithRobotCheckBox.Value)
                try
                    app.myB.send_commands(app.cmd);
                catch
                    
                end
            end
            
            % If user wants to see ocr commands on matlab command window
            if (app.Settings_Screen_ShowCommandsBeingSentCheckBox.Value)
                app.OCR_Controller_Screen_LastCommandSent.Text = app.cmd;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Welcome_Screen = uipanel(app.UIFigure);
            app.Welcome_Screen_UIAxes = uiaxes(app.Welcome_Screen);
            app.Welcome_Screen_Title = uilabel(app.Welcome_Screen);
            app.Welcome_Screen_Title.Text = 'RCP Layout Robot Controller';
            app.Welcome_Screen_STARTButton = uibutton(app.Welcome_Screen, 'push');
            app.Welcome_Screen_STARTButton.ButtonPushedFcn = createCallbackFcn(app, @Welcome_Screen_STARTButtonPushed, true);
            app.Welcome_Screen_STARTButton.Text = 'START';
            app.Welcome_Screen_STARTButton_Instructions = uilabel(app.Welcome_Screen);
            app.Welcome_Screen_STARTButton_Instructions.Text = 'press START to contine';
            app.Settings_Screen = uipanel(app.UIFigure);
            app.Settings_Screen_Title = uilabel(app.Settings_Screen);
            app.Settings_Screen_Title.Text = 'Settings';
            app.Settings_Screen_CONTINUEButton = uibutton(app.Settings_Screen, 'push');
            app.Settings_Screen_CONTINUEButton.ButtonPushedFcn = createCallbackFcn(app, @Settings_Screen_CONTINUEButtonPushed, true);
            app.Settings_Screen_CONTINUEButton.Text = 'CONTINUE';
            app.Settings_Screen_CommunicateWithRobotCheckBox = uicheckbox(app.Settings_Screen);
            app.Settings_Screen_CommunicateWithRobotCheckBox.Text = ' Communicate With Robot';
            app.Settings_Screen_UseOCRToGatherDataCheckBox = uicheckbox(app.Settings_Screen);
            app.Settings_Screen_UseOCRToGatherDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @Settings_Screen_UseOCRToGatherDataCheckBoxValueChanged, true);
            app.Settings_Screen_UseOCRToGatherDataCheckBox.Text = ' Control Using OCR data';
            app.Settings_Screen_ShowCommandsBeingSentCheckBox = uicheckbox(app.Settings_Screen);
            app.Settings_Screen_ShowCommandsBeingSentCheckBox.Text = ' Show Commands Being Sent';
            app.Settings_Screen_EnterCommandsManuallyCheckBox = uicheckbox(app.Settings_Screen);
            app.Settings_Screen_EnterCommandsManuallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @Settings_Screen_EnterCommandsManuallyCheckBoxValueChanged, true);
            app.Settings_Screen_EnterCommandsManuallyCheckBox.Text = ' Control Using Manual Commands';
            app.Settings_Screen_OutputPreferencesLabel = uilabel(app.Settings_Screen);
            app.Settings_Screen_OutputPreferencesLabel.Text = 'Output Preferences';
            app.Settings_Screen_RobotControlDesiredLabel = uilabel(app.Settings_Screen);
            app.Settings_Screen_RobotControlDesiredLabel.Text = 'Robot Control Desired';
            app.Settings_Screen_ControlUsingNEErrorCheckBox = uicheckbox(app.Settings_Screen);
            app.Settings_Screen_ControlUsingNEErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @Settings_Screen_ControlUsingNEErrorCheckBoxValueChanged, true);
            app.Settings_Screen_ControlUsingNEErrorCheckBox.Text = ' Control Using NE Error';
            app.BlankScreenTemplatePanel = uipanel(app.UIFigure);
            app.BlankScreenTemplatePanel.Title = 'Blank Screen Template';
            app.Button = uibutton(app.BlankScreenTemplatePanel, 'state');
            app.Button.Text = 'Button';
            app.Bluetooth_Setup_Screen = uipanel(app.UIFigure);
            app.Bluetooth_Setup_Screen_Title = uilabel(app.Bluetooth_Setup_Screen);
            app.Bluetooth_Setup_Screen_Title.Text = 'Bluetooth Setup';
            app.BluetoothDeviceNameEditFieldLabel = uilabel(app.Bluetooth_Setup_Screen);
            app.BluetoothDeviceNameEditFieldLabel.Text = 'Bluetooth Device Name';
            app.Bluetooth_Setup_Screen_UserInput = uieditfield(app.Bluetooth_Setup_Screen, 'text');
            app.Bluetooth_Setup_Screen_UserInput.Value = 'HC-05';
            app.Bluetooth_Setup_Screen_CONNECTButton = uibutton(app.Bluetooth_Setup_Screen, 'push');
            app.Bluetooth_Setup_Screen_CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @Bluetooth_Setup_Screen_CONNECTButtonPushed, true);
            app.Bluetooth_Setup_Screen_CONNECTButton.Text = 'CONNECT';
            app.Bluetooth_Setup_Screen_GOBACKTOSETTINGSButton = uibutton(app.Bluetooth_Setup_Screen, 'push');
            app.Bluetooth_Setup_Screen_GOBACKTOSETTINGSButton.ButtonPushedFcn = createCallbackFcn(app, @Bluetooth_Setup_Screen_GOBACKTOSETTINGSButtonPushed, true);
            app.Bluetooth_Setup_Screen_GOBACKTOSETTINGSButton.Text = 'GO BACK TO SETTINGS';
            app.Bluetooth_Setup_Screen_CONTINUEButton = uibutton(app.Bluetooth_Setup_Screen, 'push');
            app.Bluetooth_Setup_Screen_CONTINUEButton.ButtonPushedFcn = createCallbackFcn(app, @Bluetooth_Setup_Screen_CONTINUEButtonPushed, true);
            app.Bluetooth_Setup_Screen_CONTINUEButton.Text = 'CONTINUE';
            app.DeviceConnectivityStatusLabel = uilabel(app.Bluetooth_Setup_Screen);
            app.DeviceConnectivityStatusLabel.Text = 'Device Connectivity Status';
            app.Bluetooth_Setup_Screen_DeviceConnectedLamp = uilamp(app.Bluetooth_Setup_Screen);
            app.Bluetooth_Setup_Screen_ConnectMessage = uilabel(app.Bluetooth_Setup_Screen);
            app.Bluetooth_Setup_Screen_ConnectMessage.Text = 'Trying to connect to device';
            app.Manual_Controller_Screen = uipanel(app.UIFigure);
            app.Manual_Controller_Screen_Title = uilabel(app.Manual_Controller_Screen);
            app.Manual_Controller_Screen_Title.Text = 'Manual Controls';
            app.Manual_Controller_Screen_MOVEMENTSPEEDButtonGroup = uibuttongroup(app.Manual_Controller_Screen);
            app.Manual_Controller_Screen_MOVEMENTSPEEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Manual_Controller_Screen_MOVEMENTSPEEDButtonGroupSelectionChanged, true);
            app.Manual_Controller_Screen_MOVEMENTSPEEDButtonGroup.Title = 'SPEED';
            app.Manual_Controller_Screen_FastButton = uiradiobutton(app.Manual_Controller_Screen_MOVEMENTSPEEDButtonGroup);
            app.Manual_Controller_Screen_FastButton.Text = ' Fast';
            app.Manual_Controller_Screen_FastButton.Value = true;
            app.Manual_Controller_Screen_NormalButton = uiradiobutton(app.Manual_Controller_Screen_MOVEMENTSPEEDButtonGroup);
            app.Manual_Controller_Screen_NormalButton.Text = ' Normal';
            app.Manual_Controller_Screen_SlowButton = uiradiobutton(app.Manual_Controller_Screen_MOVEMENTSPEEDButtonGroup);
            app.Manual_Controller_Screen_SlowButton.Text = ' Slow';
            app.Manual_Controller_Screen_MARKButton = uibutton(app.Manual_Controller_Screen, 'push');
            app.Manual_Controller_Screen_MARKButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_MARKButtonPushed, true);
            app.Manual_Controller_Screen_MARKButton.Text = 'MARK';
            app.Manual_Controller_Screen_MotionCommands = uipanel(app.Manual_Controller_Screen);
            app.Manual_Controller_Screen_FORWARDButton = uibutton(app.Manual_Controller_Screen_MotionCommands, 'push');
            app.Manual_Controller_Screen_FORWARDButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_FORWARDButtonPushed, true);
            app.Manual_Controller_Screen_FORWARDButton.Text = 'FORWARD';
            app.Manual_Controller_Screen_REVERSEButton = uibutton(app.Manual_Controller_Screen_MotionCommands, 'push');
            app.Manual_Controller_Screen_REVERSEButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_REVERSEButtonPushed, true);
            app.Manual_Controller_Screen_REVERSEButton.Text = 'REVERSE';
            app.Manual_Controller_Screen_LEFTButton = uibutton(app.Manual_Controller_Screen_MotionCommands, 'push');
            app.Manual_Controller_Screen_LEFTButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_LEFTButtonPushed, true);
            app.Manual_Controller_Screen_LEFTButton.Text = 'LEFT';
            app.Manual_Controller_Screen_RIGHTButton = uibutton(app.Manual_Controller_Screen_MotionCommands, 'push');
            app.Manual_Controller_Screen_RIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_RIGHTButtonPushed, true);
            app.Manual_Controller_Screen_RIGHTButton.Text = 'RIGHT';
            app.Manual_Controller_Screen_ROTATELEFTButton = uibutton(app.Manual_Controller_Screen_MotionCommands, 'push');
            app.Manual_Controller_Screen_ROTATELEFTButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_ROTATELEFTButtonPushed, true);
            app.Manual_Controller_Screen_ROTATELEFTButton.Text = 'ROTATE LEFT';
            app.Manual_Controller_Screen_ROTATERIGHTButton = uibutton(app.Manual_Controller_Screen_MotionCommands, 'push');
            app.Manual_Controller_Screen_ROTATERIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_ROTATERIGHTButtonPushed, true);
            app.Manual_Controller_Screen_ROTATERIGHTButton.Text = 'ROTATE RIGHT';
            app.Manual_Controller_Screen_STOPButton = uibutton(app.Manual_Controller_Screen_MotionCommands, 'push');
            app.Manual_Controller_Screen_STOPButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_STOPButtonPushed, true);
            app.Manual_Controller_Screen_STOPButton.Text = 'STOP';
            app.Manual_Controller_Screen_SWButton = uibutton(app.Manual_Controller_Screen_MotionCommands, 'push');
            app.Manual_Controller_Screen_SWButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_SWButtonPushed, true);
            app.Manual_Controller_Screen_SWButton.Text = 'SW';
            app.Manual_Controller_Screen_NWButton = uibutton(app.Manual_Controller_Screen_MotionCommands, 'push');
            app.Manual_Controller_Screen_NWButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_NWButtonPushed, true);
            app.Manual_Controller_Screen_NWButton.Text = 'NW';
            app.Manual_Controller_Screen_NEButton = uibutton(app.Manual_Controller_Screen_MotionCommands, 'push');
            app.Manual_Controller_Screen_NEButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_NEButtonPushed, true);
            app.Manual_Controller_Screen_NEButton.Text = 'NE';
            app.Manual_Controller_Screen_SEButton = uibutton(app.Manual_Controller_Screen_MotionCommands, 'push');
            app.Manual_Controller_Screen_SEButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_SEButtonPushed, true);
            app.Manual_Controller_Screen_SEButton.Text = 'SE';
            app.Manual_Controller_Screen_PAUSEButton = uibutton(app.Manual_Controller_Screen, 'push');
            app.Manual_Controller_Screen_PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_PAUSEButtonPushed, true);
            app.Manual_Controller_Screen_PAUSEButton.Text = 'PAUSE';
            app.Manual_Controller_Screen_KILLButton = uibutton(app.Manual_Controller_Screen, 'push');
            app.Manual_Controller_Screen_KILLButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_KILLButtonPushed, true);
            app.Manual_Controller_Screen_KILLButton.Text = 'KILL';
            app.Manual_Controller_Screen_LastCommandSent_Panel = uipanel(app.Manual_Controller_Screen);
            app.Manual_Controller_Screen_LastCommandSent_Title = uilabel(app.Manual_Controller_Screen_LastCommandSent_Panel);
            app.Manual_Controller_Screen_LastCommandSent_Title.Text = 'Most Recent Command Sent:';
            app.Manual_Controller_Screen_LastCommandSent_Holder = uipanel(app.Manual_Controller_Screen_LastCommandSent_Panel);
            app.Manual_Controller_Screen_LastCommandSent = uilabel(app.Manual_Controller_Screen_LastCommandSent_Holder);
            app.Manual_Controller_Screen_LastCommandSent.Text = '';
            app.Manual_Controller_Screen_ACTION1Button = uibutton(app.Manual_Controller_Screen, 'push');
            app.Manual_Controller_Screen_ACTION1Button.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_ACTION1ButtonPushed, true);
            app.Manual_Controller_Screen_ACTION1Button.Text = 'ACTION 1';
            app.Manual_Controller_Screen_ACTION2Button = uibutton(app.Manual_Controller_Screen, 'push');
            app.Manual_Controller_Screen_ACTION2Button.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_ACTION2ButtonPushed, true);
            app.Manual_Controller_Screen_ACTION2Button.Text = 'ACTION 2';
            app.Manual_Controller_Screen_ACTION3Button = uibutton(app.Manual_Controller_Screen, 'push');
            app.Manual_Controller_Screen_ACTION3Button.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_ACTION3ButtonPushed, true);
            app.Manual_Controller_Screen_ACTION3Button.Text = 'ACTION 3';
            app.Manual_Controller_Screen_ACTION4Button = uibutton(app.Manual_Controller_Screen, 'push');
            app.Manual_Controller_Screen_ACTION4Button.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_ACTION4ButtonPushed, true);
            app.Manual_Controller_Screen_ACTION4Button.Text = 'ACTION 4';
            app.Manual_Controller_Screen_BACKTOOCRCONTROLButton = uibutton(app.Manual_Controller_Screen, 'push');
            app.Manual_Controller_Screen_BACKTOOCRCONTROLButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_Controller_Screen_BACKTOOCRCONTROLButtonPushed, true);
            app.Manual_Controller_Screen_BACKTOOCRCONTROLButton.Text = 'USE OCR CONTROL';
            app.OCR_Setup_Screen = uipanel(app.UIFigure);
            app.OCR_Setup_Screen_Title = uilabel(app.OCR_Setup_Screen);
            app.OCR_Setup_Screen_Title.Text = 'OCR Setup';
            app.OCR_Setup_Screen_SelectOCRReadLocationButton_Instructions = uilabel(app.OCR_Setup_Screen);
            app.OCR_Setup_Screen_SelectOCRReadLocationButton_Instructions.Text = 'Select the Section of the screen containing the MAGNET Field N&E error data.';
            app.OCR_Setup_Screen_SelectOCRReadLocationButton = uibutton(app.OCR_Setup_Screen, 'push');
            app.OCR_Setup_Screen_SelectOCRReadLocationButton.ButtonPushedFcn = createCallbackFcn(app, @OCR_Setup_Screen_SelectOCRReadLocationButtonPushed, true);
            app.OCR_Setup_Screen_SelectOCRReadLocationButton.Text = 'Select OCR Read Location';
            app.OCR_Setup_Screen_PreviewSelectionButton = uibutton(app.OCR_Setup_Screen, 'push');
            app.OCR_Setup_Screen_PreviewSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @OCR_Setup_Screen_PreviewSelectionButtonPushed, true);
            app.OCR_Setup_Screen_PreviewSelectionButton.Text = 'Preview OCR Read Location';
            app.OCR_Setup_Screen_GOBACKTOSETTINGSButton = uibutton(app.OCR_Setup_Screen, 'push');
            app.OCR_Setup_Screen_GOBACKTOSETTINGSButton.ButtonPushedFcn = createCallbackFcn(app, @OCR_Setup_Screen_GOBACKTOSETTINGSButtonPushed, true);
            app.OCR_Setup_Screen_GOBACKTOSETTINGSButton.Text = 'GO BACK TO SETTINGS';
            app.OCR_Setup_Screen_CONTINUEButton = uibutton(app.OCR_Setup_Screen, 'push');
            app.OCR_Setup_Screen_CONTINUEButton.ButtonPushedFcn = createCallbackFcn(app, @OCR_Setup_Screen_CONTINUEButtonPushed, true);
            app.OCR_Setup_Screen_CONTINUEButton.Text = 'CONTINUE';
            app.OCR_Setup_Screen_UIAxes_Holder = uipanel(app.OCR_Setup_Screen);
            app.OCR_Setup_Screen_UIAxes = uiaxes(app.OCR_Setup_Screen_UIAxes_Holder);
            app.Pause_Menu_Screen = uipanel(app.UIFigure);
            app.Pause_Menu_Screen_Title = uilabel(app.Pause_Menu_Screen);
            app.Pause_Menu_Screen_Title.Text = 'Pause Menu';
            app.Pause_Menu_Screen_RETURNTOPREVIOUSSCREENButton = uibutton(app.Pause_Menu_Screen, 'push');
            app.Pause_Menu_Screen_RETURNTOPREVIOUSSCREENButton.ButtonPushedFcn = createCallbackFcn(app, @Pause_Menu_Screen_RETURNTOPREVIOUSSCREENButtonPushed, true);
            app.Pause_Menu_Screen_RETURNTOPREVIOUSSCREENButton.Text = 'RETURN TO PREVIOUS SCREEN';
            app.Pause_Menu_Screen_GOTOSETTINGSButton = uibutton(app.Pause_Menu_Screen, 'push');
            app.Pause_Menu_Screen_GOTOSETTINGSButton.ButtonPushedFcn = createCallbackFcn(app, @Pause_Menu_Screen_GOTOSETTINGSButtonPushed, true);
            app.Pause_Menu_Screen_GOTOSETTINGSButton.Text = 'GO TO SETTINGS';
            app.Pause_Menu_Screen_QUITButton = uibutton(app.Pause_Menu_Screen, 'push');
            app.Pause_Menu_Screen_QUITButton.ButtonPushedFcn = createCallbackFcn(app, @Pause_Menu_Screen_QUITButtonPushed, true);
            app.Pause_Menu_Screen_QUITButton.Text = 'QUIT';
            app.OCR_Controller_Screen = uipanel(app.UIFigure);
            app.OCR_Controller_Screen_Title = uilabel(app.OCR_Controller_Screen);
            app.OCR_Controller_Screen_Title.Text = 'Robot Controller Using OCR Data';
            app.OCR_Controller_Screen_InitOrientationButton = uibutton(app.OCR_Controller_Screen, 'push');
            app.OCR_Controller_Screen_InitOrientationButton.Text = 'CORRECT INITIAL ORIENTATION';
            app.OCR_Controller_Screen_InitOrientationButton_Instructions = uilabel(app.OCR_Controller_Screen);
            app.OCR_Controller_Screen_InitOrientationButton_Instructions.Text = 'Sync Robot Orientation With Total Station';
            app.OCR_Controller_Screen_PreviewSelectionButton = uibutton(app.OCR_Controller_Screen, 'push');
            app.OCR_Controller_Screen_PreviewSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @OCR_Controller_Screen_PreviewSelectionButtonPushed, true);
            app.OCR_Controller_Screen_PreviewSelectionButton.Text = 'Preview OCR Read Location';
            app.OCR_Controller_Screen_UIAxes_Holder = uipanel(app.OCR_Controller_Screen);
            app.OCR_Controller_Screen_UIAxes = uiaxes(app.OCR_Controller_Screen_UIAxes_Holder);
            app.OCR_Controller_Screen_PAUSEButton = uibutton(app.OCR_Controller_Screen, 'push');
            app.OCR_Controller_Screen_PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @OCR_Controller_Screen_PAUSEButtonPushed, true);
            app.OCR_Controller_Screen_PAUSEButton.Text = 'PAUSE';
            app.OCR_Controller_Screen_EnableCommands = uibutton(app.OCR_Controller_Screen, 'push');
            app.OCR_Controller_Screen_EnableCommands.ButtonPushedFcn = createCallbackFcn(app, @OCR_Controller_Screen_EnableCommandsButtonPushed, true);
            app.OCR_Controller_Screen_EnableCommands.Text = 'SEND ROBOT TO DESTINATION';
            app.OCR_Controller_Screen_STOPROBOTButton = uibutton(app.OCR_Controller_Screen, 'push');
            app.OCR_Controller_Screen_STOPROBOTButton.ButtonPushedFcn = createCallbackFcn(app, @OCR_Controller_Screen_STOPROBOTButtonPushed, true);
            app.OCR_Controller_Screen_STOPROBOTButton.Text = 'STOP ROBOT';
            app.OCR_Controller_Screen_AdjustOCRButton = uibutton(app.OCR_Controller_Screen, 'push');
            app.OCR_Controller_Screen_AdjustOCRButton.ButtonPushedFcn = createCallbackFcn(app, @OCR_Controller_Screen_AdjustOCRButtonPushed, true);
            app.OCR_Controller_Screen_AdjustOCRButton.Text = 'Adjust OCR Read Location';
            app.OCR_Controller_Screen_LastCommandSent_Panel = uipanel(app.OCR_Controller_Screen);
            app.OCR_Controller_Screen_LastCommandSent_Title = uilabel(app.OCR_Controller_Screen_LastCommandSent_Panel);
            app.OCR_Controller_Screen_LastCommandSent_Title.Text = 'Most Recent Command Sent:';
            app.OCR_Controller_Screen_LastCommandSent_Holder = uipanel(app.OCR_Controller_Screen_LastCommandSent_Panel);
            app.OCR_Controller_Screen_LastCommandSent = uilabel(app.OCR_Controller_Screen_LastCommandSent_Holder);
            app.OCR_Controller_Screen_LastCommandSent.Text = '';
            app.OCR_Controller_Screen_MANUALOVERRIDEButton = uibutton(app.OCR_Controller_Screen, 'push');
            app.OCR_Controller_Screen_MANUALOVERRIDEButton.ButtonPushedFcn = createCallbackFcn(app, @OCR_Controller_Screen_MANUALOVERRIDEButtonPushed, true);
            app.OCR_Controller_Screen_MANUALOVERRIDEButton.Text = 'MANUAL OVERRIDE';
            app.Panel = uipanel(app.OCR_Controller_Screen);
            app.updatedNorthingErrorLabel = uilabel(app.Panel);
            app.updatedNorthingErrorLabel.Text = '';
            app.ErrorNLabel = uilabel(app.Panel);
            app.ErrorNLabel.Text = 'Error N:';
            app.Panel_2 = uipanel(app.OCR_Controller_Screen);
            app.updatedEastingErrorLabel = uilabel(app.Panel_2);
            app.updatedEastingErrorLabel.Text = '';
            app.ErrorELabel = uilabel(app.Panel_2);
            app.ErrorELabel.Text = 'Error E:';
            app.Panel_3 = uipanel(app.OCR_Controller_Screen);
            app.updatedDesiredOffsetLabel = uilabel(app.Panel_3);
            app.updatedDesiredOffsetLabel.Text = '';
            app.CurrentDesiredOffsetLabel = uilabel(app.Panel_3);
            app.CurrentDesiredOffsetLabel.Text = 'Current Desired Offset:';
            app.NE_Error_Controller_Screen = uipanel(app.UIFigure);
            app.NE_Error_Controller_Screen_Title = uilabel(app.NE_Error_Controller_Screen);
            app.NE_Error_Controller_Screen_Title.Text = 'Robot Controller Entering NE Error';
            app.NorthingErrorEditFieldLabel = uilabel(app.NE_Error_Controller_Screen);
            app.NorthingErrorEditFieldLabel.Text = 'Northing Error';
            app.NE_Error_Controller_Screen_NorthingErrorInput = uieditfield(app.NE_Error_Controller_Screen, 'numeric');
            app.EastingErrorEditFieldLabel = uilabel(app.NE_Error_Controller_Screen);
            app.EastingErrorEditFieldLabel.Text = 'Easting Error';
            app.NE_Error_Controller_Screen_EastingErrorInput = uieditfield(app.NE_Error_Controller_Screen, 'numeric');
            app.NE_Error_Controller_Screen_SendCommandButton = uibutton(app.NE_Error_Controller_Screen, 'push');
            app.NE_Error_Controller_Screen_SendCommandButton.ButtonPushedFcn = createCallbackFcn(app, @NE_Error_Controller_Screen_SendCommandButtonPushed, true);
            app.NE_Error_Controller_Screen_SendCommandButton.Text = 'Send Command Using Error Input';
            app.NE_Error_Controller_Screen_STOPROBOTButton = uibutton(app.NE_Error_Controller_Screen, 'push');
            app.NE_Error_Controller_Screen_STOPROBOTButton.ButtonPushedFcn = createCallbackFcn(app, @NE_Error_Controller_Screen_STOPROBOTButtonPushed, true);
            app.NE_Error_Controller_Screen_STOPROBOTButton.Text = 'STOP ROBOT';
            app.NE_Error_Controller_Screen_LastCommandSent_Panel = uipanel(app.NE_Error_Controller_Screen);
            app.NE_Error_Controller_Screen_LastCommandSent_Title = uilabel(app.NE_Error_Controller_Screen_LastCommandSent_Panel);
            app.NE_Error_Controller_Screen_LastCommandSent_Title.Text = 'Most Recent Command Sent:';
            app.NE_Error_Controller_Screen_LastCommandSent_Holder = uipanel(app.NE_Error_Controller_Screen_LastCommandSent_Panel);
            app.NE_Error_Controller_Screen_LastCommandSent = uilabel(app.NE_Error_Controller_Screen_LastCommandSent_Holder);
            app.NE_Error_Controller_Screen_LastCommandSent.Text = '';
            app.NE_Error_Controller_Screen_PAUSEButton = uibutton(app.NE_Error_Controller_Screen, 'push');
            app.NE_Error_Controller_Screen_PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @NE_Error_Controller_Screen_PAUSEButtonPushed, true);
            app.NE_Error_Controller_Screen_PAUSEButton.Text = 'PAUSE';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RCP_controller_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end